classdef AdAnalyser_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFig                    matlab.ui.Figure
        saveSettings               matlab.ui.control.Button
        loadSettings               matlab.ui.control.Button
        analyse                    matlab.ui.control.Button
        uipanel1                   matlab.ui.container.Panel
        edaFiles                   matlab.ui.control.ListBox
        eegFiles                   matlab.ui.control.ListBox
        text3                      matlab.ui.control.Label
        browseEDA                  matlab.ui.control.Button
        browseEEG                  matlab.ui.control.Button
        text4                      matlab.ui.control.Label
        StimuIntDef                matlab.ui.control.EditField
        browseStimuIntDefinitions  matlab.ui.control.Button
        hrvFiles                   matlab.ui.control.ListBox
        text13                     matlab.ui.control.Label
        browseHRV                  matlab.ui.control.Button
        outputDirectory            matlab.ui.control.EditField
        browseOutputDirectory      matlab.ui.control.Button
        text15                     matlab.ui.control.Label
        text14                     matlab.ui.control.Label
        videoString                matlab.ui.control.EditField
        browsevideoString          matlab.ui.control.Button
        text14_2                   matlab.ui.control.Label
        prepare                    matlab.ui.control.Button
        filter                     matlab.ui.control.Button
        settings                   matlab.ui.control.Button
        AdAnalyserLabel            matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        BrainMap                   matlab.ui.control.Button
    end

    %This is the Main GUI of the AdAnalyser.

    %Here the user can define the different paths for the stimulus, the 
    %output or the video. In addition, the data of the experiment are imported here. 
    %With Load and Save Settings the user can create and reopen presets.
    %Further setting options can be accessed under the "Settings" button.
    
    methods (Access = private)
        %function to call the Electrodes Dialog GUI
        function electrodesSettings_Callback(app)
            ElectrodesDialog;
        end
        
        %Delete if no erros accurce
        %function lowerEEGThreshold_CreateFcn(app, hObject)
        %    if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %        set(hObject,'BackgroundColor','white');
        %    end
        %end
        
        %function qualityIndex_CreateFcn(app, hObject)
        %    if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %        set(hObject,'BackgroundColor','white');
        %    end
        %end
        
        %function upperEEGThreshold_CreateFcn(app, hObject)
        %    if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %        set(hObject,'BackgroundColor','white');
        %    end
        %end 
        
        %updates UI window if called
        function updateUI(app)
            global conf;
            app.outputDirectory.Value = conf.OutputDirectory;
            app.videoString.Value = conf.videoString;
            app.StimuIntDef.Value = conf.StimuIntDef;
            app.edaFiles.Items= conf.EDAFiles;
            app.eegFiles.Items = conf.EEGFiles;
            app.hrvFiles.Items = conf.HRVFiles;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AdAnalyser_OpeningFcn(app)
            %opens main GUI and centers it
            movegui(app.MainFig,"center");
            global conf
            global configManager
            conf = Config;
            configManager = ConfigManager;
        end

        % Value changed function: StimuIntDef
        function StimuIntDef_Callback(app, event)
            %prints the choosen directory for Stimulus
            global conf
            input = get(hObject,'String');
            conf.StimuIntDef = input;
        end

        % Button pushed function: analyse
        function analyse_Callback(app, event)
            %Callback for Analyse Button
            %starts the Analyse Action
            global conf;
            global data;
            global eegdevice edadevice hrvdevice;
            fprintf('\nAnalyse all data sets');
            %initialize the AnalyseAction.m code
            a = AnalyseAction();
            a.analyse(data,conf,eegdevice,edadevice,hrvdevice);
            fprintf('\nDone\n');
            % activate next button
            set(app.BrainMap,'enable','on');
        end

        % Button pushed function: browseEDA
        function browseEDA_Callback(app, event)
            %function to browse the EDA Files Button
            %user can choose EDA Files from browser window and GUI gets
            %updated accordingly
            global conf;
            %get file path/name
            [filenames, pathname] = uigetfile('.\data\*.txt;*.csv', 'Select EDA files','MultiSelect', 'on');
            empty = isempty(pathname) || pathname(1:1)==0;
            cutOffString = strfind(pathname,'\data');
            pathname = strcat('.',pathname(cutOffString:end));
            if empty==0
                if ~iscell(filenames) && ischar(filenames)
                    filenames = {filenames}; % force it to be a cell array of strings
                end
                edaFilesName = cell(size(filenames));
                for file = 1:numel(filenames)
                    fullpath = fullfile(pathname,filenames{file});
                    edaFilesName = [edaFilesName, fullpath];
                end
                edaFilesName(cellfun('isempty',edaFilesName)) = [];
                conf.EDAFiles = edaFilesName; %save Filenames in conf
                app.edaFiles.Items = conf.EDAFiles; %display
                figure(app.MainFig) %update GUI
            end
        end

        % Button pushed function: browseEEG
        function browseEEG_Callback(app, event)
            %function to browse the EEG Files Button
            %see browseEDA_Callback
            global conf;
            [filenames, pathname] = uigetfile('.\data\*.txt;*.csv', 'Select EEG files','MultiSelect', 'on');
            empty = isempty(pathname) || pathname(1:1)==0;
            cutOffString = strfind(pathname,'\data');
            pathname = strcat('.',pathname(cutOffString:end));
            if empty==0
                if ~iscell(filenames) && ischar(filenames)
                    filenames = {filenames};
                end
                eegFilesNames = cell(size(filenames));
                for file = 1:numel(filenames)
                    fullpath = fullfile(pathname,filenames{file});
                    eegFilesNames = [eegFilesNames, fullpath];
                end
                eegFilesNames(cellfun('isempty',eegFilesNames)) = [];
                conf.EEGFiles = eegFilesNames;
                app.eegFiles.Items = conf.EEGFiles;
                figure(app.MainFig)
            end
        end

        % Button pushed function: browseHRV
        function browseHRV_Callback(app, event)
            %function to browse the HRV Files Button
            %see browseEDA_Callback
            global conf;
            [filenames, pathname] = uigetfile('.\data\*.txt;*.csv', 'Select HRV files','MultiSelect', 'on');
            empty = isempty(pathname) || pathname(1:1)==0;
            cutOffString = strfind(pathname,'\data');
            pathname = strcat('.',pathname(cutOffString:end));
            if empty==0
                if ~iscell(filenames) && ischar(filenames)
                    filenames = {filenames};
                end
                hrvFilesNames = cell(size(filenames));
                for file = 1:numel(filenames)
                    fullpath = fullfile(pathname,filenames{file});
                    hrvFilesNames = [hrvFilesNames, fullpath];
                end
                hrvFilesNames(cellfun('isempty',hrvFilesNames)) = [];
                conf.HRVFiles = hrvFilesNames;
                app.hrvFiles.Items = conf.HRVFiles;
                figure(app.MainFig)
            end
        end

        % Button pushed function: browseOutputDirectory
        function browseOutput_Callback(app, event)
            %function to determind the Output directory
            global conf;
            outputDirectoryName=uigetdir(".\",'Choose an Output Directory');
            cutOffString = strfind(outputDirectoryName,'\output');
            outputDirectoryName = strcat('.',outputDirectoryName(cutOffString:end));
            if outputDirectoryName~=0
                conf.OutputDirectory=outputDirectoryName;
                app.outputDirectory.Value = conf.OutputDirectory;
                figure(app.MainFig)
            end
        end

        % Button pushed function: browseStimuIntDefinitions
        function browseStimuIntDefinitions_Callback(app, event)
            %function to import the AdIndex file
            global conf;
            [filename, pathname]=uigetfile('.\config\AdIndex\*.txt;*.csv','Select a File with SimulationsInterval');
            cutOffString = strfind(pathname,'\config');
            pathname = strcat('.',pathname(cutOffString:end));
            if filename~=0
                conf.StimuIntDef = fullfile(pathname, filename);
                app.StimuIntDef.Value = conf.StimuIntDef;
                figure(app.MainFig)
            end
        end

        % Button pushed function: filter
        function filter_Callback(app, event)
            %Callback for Filter Button
            %starts the Filter Action
            global conf
            global data
            global eegdevice edadevice hrvdevice;
            fprintf('\nFilter subjects');
            %initialize the FilterAction.m code
            filterClass = FilterAction();
            [data,conf] = filterClass.filter(data,conf,eegdevice,edadevice,hrvdevice);
            set(app.analyse,'enable','on');
            fprintf('\nDone\n');
        end

        % Button pushed function: loadSettings
        function loadSettings_Callback(app, event)
            %Callback for the load settings button
            %Imports a save preset and updates GUI 
            global conf;
            global configManager;
            global eegdevice edadevice hrvdevice;
            %graps data from variable conf and updates GUI
            [conf,eegdevice,edadevice,hrvdevice] = configManager.load(conf);
            updateUI(app);
            figure(app.MainFig)
            set(app.analyse,'enable','off');
            set(app.filter,'enable','off');
            set(app.BrainMap,'enable','off');
        end

        % Value changed function: outputDirectory
        function outputDir_Callback(app, event)
            global conf
            input = get(hObject,'String');
            conf.OutputDirectory = input;
        end

        % Button pushed function: prepare
        function prepare_Callback(app, event)
            %Callback for Prepare Button
            %starts the prepare Action
            global conf;
            global data;
            global eegdevice edadevice hrvdevice;
            fprintf('\nPrepare subjects');
            %initialize the PrepareAction.m code
            action = PrepareAction();
            [data,conf] = action.prepare(conf,eegdevice,edadevice,hrvdevice);
            if (data.isValid)
                fprintf('\nDone\n');
                set(app.filter,'enable','on');
            end
        end

        % Button pushed function: saveSettings
        function saveSettings_Callback(app, event)
            %Callback for Save Settings Button
            global conf;
            global configManager
            configManager.save(conf); %Save settings in conf
            figure(app.MainFig)
        end

        % Button pushed function: settings
        function settings_Callback(app, event)
            ConfigDialog_App;
        end

        % Button pushed function: browsevideoString
        function browsevideoStringButtonPushed(app, event)
            %function to get the video file name
            global conf;
            dname = uigetdir('.\data','Select the Video Directory');
            cutOffString = strfind(dname,'\data');
            dname = strcat('.',dname(cutOffString:end));
            if dname~=0
                conf.videoString = dname;
                app.videoString.Value = conf.videoString; 
                figure(app.MainFig)
            end
        end

        % Callback function
        function exportButtonPushed(app, event)
            ExportDialog_App;
        end

        % Button pushed function: BrainMap
        function BrainMapButtonPushed(app, event)
            BrainMapDialog;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFig = uifigure('Visible', 'off');
            app.MainFig.Name = 'AdAnalyser';
            app.saveSettings = uibutton(app.MainFig, 'push');
            app.saveSettings.ButtonPushedFcn = createCallbackFcn(app, @saveSettings_Callback, true);
            app.saveSettings.Text = 'Save Settings';
            app.loadSettings = uibutton(app.MainFig, 'push');
            app.loadSettings.ButtonPushedFcn = createCallbackFcn(app, @loadSettings_Callback, true);
            app.loadSettings.Text = 'Load Settings';
            app.analyse = uibutton(app.MainFig, 'push');
            app.analyse.ButtonPushedFcn = createCallbackFcn(app, @analyse_Callback, true);
            app.analyse.Enable = 'off';
            app.analyse.Text = 'Analyse (3)';
            app.uipanel1 = uipanel(app.MainFig);
            app.uipanel1.Title = 'Directory Settings';
            app.edaFiles = uilistbox(app.uipanel1);
            app.edaFiles.Items = {};
            app.edaFiles.Value = {};
            app.eegFiles = uilistbox(app.uipanel1);
            app.eegFiles.Items = {};
            app.eegFiles.Value = {};
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'EEG Files';
            app.browseEDA = uibutton(app.uipanel1, 'push');
            app.browseEDA.ButtonPushedFcn = createCallbackFcn(app, @browseEDA_Callback, true);
            app.browseEDA.Text = 'Browse';
            app.browseEEG = uibutton(app.uipanel1, 'push');
            app.browseEEG.ButtonPushedFcn = createCallbackFcn(app, @browseEEG_Callback, true);
            app.browseEEG.Text = 'Browse';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Stimulus Directory';
            app.StimuIntDef = uieditfield(app.uipanel1, 'text');
            app.StimuIntDef.ValueChangedFcn = createCallbackFcn(app, @StimuIntDef_Callback, true);
            app.browseStimuIntDefinitions = uibutton(app.uipanel1, 'push');
            app.browseStimuIntDefinitions.ButtonPushedFcn = createCallbackFcn(app, @browseStimuIntDefinitions_Callback, true);
            app.browseStimuIntDefinitions.Text = 'Browse';
            app.hrvFiles = uilistbox(app.uipanel1);
            app.hrvFiles.Items = {};
            app.hrvFiles.Value = {};
            app.text13 = uilabel(app.uipanel1);
            app.text13.Text = 'HRV Files';
            app.browseHRV = uibutton(app.uipanel1, 'push');
            app.browseHRV.ButtonPushedFcn = createCallbackFcn(app, @browseHRV_Callback, true);
            app.browseHRV.Text = 'Browse';
            app.outputDirectory = uieditfield(app.uipanel1, 'text');
            app.outputDirectory.ValueChangedFcn = createCallbackFcn(app, @outputDir_Callback, true);
            app.browseOutputDirectory = uibutton(app.uipanel1, 'push');
            app.browseOutputDirectory.ButtonPushedFcn = createCallbackFcn(app, @browseOutput_Callback, true);
            app.browseOutputDirectory.Text = 'Browse';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Text = 'EDA Files';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Text = 'Output Directory';
            app.videoString = uieditfield(app.uipanel1, 'text');
            app.browsevideoString = uibutton(app.uipanel1, 'push');
            app.browsevideoString.ButtonPushedFcn = createCallbackFcn(app, @browsevideoStringButtonPushed, true);
            app.browsevideoString.Text = 'Browse';
            app.text14_2 = uilabel(app.uipanel1);
            app.text14_2.Text = 'Video Directory';
            app.prepare = uibutton(app.MainFig, 'push');
            app.prepare.ButtonPushedFcn = createCallbackFcn(app, @prepare_Callback, true);
            app.prepare.Text = 'Prepare (1)';
            app.filter = uibutton(app.MainFig, 'push');
            app.filter.ButtonPushedFcn = createCallbackFcn(app, @filter_Callback, true);
            app.filter.Enable = 'off';
            app.filter.Text = 'Filter (2)';
            app.settings = uibutton(app.MainFig, 'push');
            app.settings.ButtonPushedFcn = createCallbackFcn(app, @settings_Callback, true);
            app.settings.Text = 'Settings';
            app.AdAnalyserLabel = uilabel(app.MainFig);
            app.AdAnalyserLabel.Text = ' AdAnalyser';
            app.Label = uilabel(app.MainFig);
            app.Label.Text = '';
            app.BrainMap = uibutton(app.MainFig, 'push');
            app.BrainMap.ButtonPushedFcn = createCallbackFcn(app, @BrainMapButtonPushed, true);
            app.BrainMap.Enable = 'off';
            app.BrainMap.Text = 'BrainMap (4)';
            % Show the figure after all components are created
            app.MainFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdAnalyser_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFig)

            % Execute the startup function
            runStartupFcn(app, @AdAnalyser_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFig)
        end
    end
end