classdef BrainMapDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BorderLabel               matlab.ui.control.Label
        BrainMapDialogLabel       matlab.ui.control.Label
        MainPanel                 matlab.ui.container.Panel
        VideoResButtonGroup       matlab.ui.container.ButtonGroup
        p480Button                matlab.ui.control.RadioButton
        p720Button                matlab.ui.control.RadioButton
        p1080Button               matlab.ui.control.RadioButton
        TimeframePanel            matlab.ui.container.Panel
        startTreshold             matlab.ui.control.EditField
        endTreshold               matlab.ui.control.EditField
        startLabel                matlab.ui.control.Label
        endLabel                  matlab.ui.control.Label
        StimulusandIntervalPanel  matlab.ui.container.Panel
        StimulusDropDownLabel     matlab.ui.control.Label
        StimulusDropDown          matlab.ui.control.DropDown
        IntervalmsLabel           matlab.ui.control.Label
        Interval                  matlab.ui.control.EditField
        Render                    matlab.ui.control.Button
        Render_2                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global data
            StimuInts = data.stimuIntDefs;
            count = 1;
            
            % get all Stimulus Intervals, which can be analysed
            for i = 1:length(StimuInts)
                if StimuInts{i}.stimuIntType >= 4
                    options{count} = StimuInts{i}.stimuIntDescrp;
                    count = count + 1;
                end
            end
            
            % add options to drop down menu
            app.StimulusDropDown.Items = options;
        end

        % Button pushed function: Render
        function RenderButtonPushed(app, event)
            if app.Interval.Value > 1000
                % XXX
                fprintf('\nCreate Brain Map');
                % get stimulus, timeframe and interval
                Stimulus = app.StimulusDropDown.Value;
                interval = str2double(app.Interval.Value);
                timeframe(1) = str2double(app.startTreshold.Value);
                timeframe(2) = str2double(app.endTreshold.Value);
                
                % get choosen video resolution
                if app.p480Button.Value == 1
                    videoRes = [640,480];
                elseif app.p720Button.Value == 1
                    videoRes = [1280,720];
                elseif app.p1080Button.Value == 1
                    videoRes = [1920,1080];
                end
                
                BrainMapAction(Stimulus,interval,timeframe,videoRes)
                
                fprintf('Done\n');
                
            elseif app.Interval.Value < 1000
                warndlg(['Values for Interval is wrong!' ,newline, 'Input value needs to be 1000ms or higher'],'Invalid Interval');
            end
        end

        % Button pushed function: Render_2
        function Render_2ButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BorderLabel = uilabel(app.UIFigure);
            app.BorderLabel.Text = {''; ''};
            app.BrainMapDialogLabel = uilabel(app.UIFigure);
            app.BrainMapDialogLabel.Text = ' BrainMap Dialog';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Title = 'Settings for video rendering';
            app.VideoResButtonGroup = uibuttongroup(app.MainPanel);
            app.VideoResButtonGroup.Title = 'Video resolution';
            app.p480Button = uiradiobutton(app.VideoResButtonGroup);
            app.p480Button.Text = '480p';
            app.p480Button.Value = true;
            app.p720Button = uiradiobutton(app.VideoResButtonGroup);
            app.p720Button.Text = '720p';
            app.p1080Button = uiradiobutton(app.VideoResButtonGroup);
            app.p1080Button.Text = '1080p';
            app.TimeframePanel = uipanel(app.MainPanel);
            app.TimeframePanel.Title = 'Timeframe';
            app.startTreshold = uieditfield(app.TimeframePanel, 'text');
            app.startTreshold.Value = '0';
            app.endTreshold = uieditfield(app.TimeframePanel, 'text');
            app.endTreshold.Value = '1000';
            app.startLabel = uilabel(app.TimeframePanel);
            app.startLabel.Text = 'from [ms]';
            app.endLabel = uilabel(app.TimeframePanel);
            app.endLabel.Text = ' to [ms]';
            app.StimulusandIntervalPanel = uipanel(app.MainPanel);
            app.StimulusandIntervalPanel.Title = 'Stimulus and Interval';
            app.StimulusDropDownLabel = uilabel(app.StimulusandIntervalPanel);
            app.StimulusDropDownLabel.Text = 'Stimulus     ';
            app.StimulusDropDown = uidropdown(app.StimulusandIntervalPanel);
            app.IntervalmsLabel = uilabel(app.StimulusandIntervalPanel);
            app.IntervalmsLabel.Text = 'Interval [ms]';
            app.Interval = uieditfield(app.StimulusandIntervalPanel, 'text');
            app.Interval.Value = '1000';
            app.Render = uibutton(app.UIFigure, 'push');
            app.Render.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.Render.Text = 'Render';
            app.Render_2 = uibutton(app.UIFigure, 'push');
            app.Render_2.ButtonPushedFcn = createCallbackFcn(app, @Render_2ButtonPushed, true);
            app.Render_2.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainMapDialog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end