classdef ConfigDialog_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        uipanel1                   matlab.ui.container.Panel
        behavioralCharacteristics  matlab.ui.control.CheckBox
        rawEEG                     matlab.ui.control.CheckBox
        frequency                  matlab.ui.control.CheckBox
        rawEDA                     matlab.ui.control.CheckBox
        rawHRV                     matlab.ui.control.CheckBox
        detrendedEDA               matlab.ui.control.CheckBox
        subStimuIntEDA             matlab.ui.control.CheckBox
        qualityFigures             matlab.ui.control.CheckBox
        statistics                 matlab.ui.control.CheckBox
        recurrenceFig              matlab.ui.control.CheckBox
        topoplot                   matlab.ui.control.CheckBox
        brainactivity              matlab.ui.control.CheckBox
        uipanel3                   matlab.ui.container.Panel
        text7                      matlab.ui.control.Label
        recurrenceTreshold         matlab.ui.control.EditField
        ok                         matlab.ui.control.Button
        Cancel                     matlab.ui.control.Button
        allOutputs                 matlab.ui.control.Button
        noOutputs                  matlab.ui.control.Button
        uipanel5                   matlab.ui.container.Panel
        ElectrodeDialogButton      matlab.ui.control.Button
        uipanel2                   matlab.ui.container.Panel
        text5                      matlab.ui.control.Label
        text4                      matlab.ui.control.Label
        text2                      matlab.ui.control.Label
        lowerEEGThreshold          matlab.ui.control.EditField
        text1                      matlab.ui.control.Label
        upperEEGThreshold          matlab.ui.control.EditField
        text3                      matlab.ui.control.Label
        qualityIndex               matlab.ui.control.EditField
        text6                      matlab.ui.control.Label
        EEGCutoffValue             matlab.ui.control.EditField
        EEGCutoffValueInSec        matlab.ui.control.Label
        sLabel                     matlab.ui.control.Label
        uipanel6                   matlab.ui.container.Panel
        text21_3                   matlab.ui.control.Label
        UserFrameRate              matlab.ui.control.EditField
        videoOutput                matlab.ui.control.CheckBox
        uipanel7                   matlab.ui.container.Panel
        text21                     matlab.ui.control.Label
        TopoRange                  matlab.ui.control.EditField
        text21_2                   matlab.ui.control.Label
        BrainRange                 matlab.ui.control.EditField
        uipanel8                   matlab.ui.container.Panel
        numSubjects                matlab.ui.control.EditField
        text24                     matlab.ui.control.Label
        SettingsforanalyseLabel    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        ExportSettingsButton       matlab.ui.control.Button
    end

    %This is the Settings GUI for the AdAnalyser.

    % The user can set the different settings for the analysis.
    % The output of the program can be switched on and off, the quality settings 
    % for the EEG analysis can be set and the settings for different plots can 
    % be defined. For example, the user can set different intervals at which 
    % the 2D Topology Maps plot is generated. 
    % The user can also open the electrode selection.
    %
    % Author: Tim Kreitzberg
    %

    %function to update GUI accordingly to their values in conf
    methods (Access = private)
        function updateUI(app)
            global conf;
            app.rawEEG.Value = conf.EEG_DEVICE_USED;
            app.rawEDA.Value = conf.EDA_DEVICE_USED;
            app.qualityFigures.Value = conf.QualityFig;
            app.detrendedEDA.Value = conf.DetrendedEDAFig;
            app.frequency.Value = conf.FrequencyFig;
            app.statistics.Value = conf.Statistics;
            app.recurrenceFig.Value = conf.RecurrenceFig;
            app.behavioralCharacteristics.Value = conf.BehaveFig;
            app.rawHRV.Value = conf.HRV_DEVICE_USED;
            app.subStimuIntEDA.Value = conf.SubStimuIntEDAFig;
            app.topoplot.Value = conf.topoplot;
            app.brainactivity.Value = conf.brainactivity;
            app.upperEEGThreshold.Value = num2str(conf.UpperThreshold);
            app.lowerEEGThreshold.Value = num2str(conf.LowerThreshold);
            app.qualityIndex.Value = num2str(conf.QualityIndex);
            app.recurrenceTreshold.Value = num2str(conf.RecurrenceThreshold);
            app.EEGCutoffValue.Value = num2str(conf.EEGCutoffValue);
            app.TopoRange.Value = num2str(conf.TopoRange);
            app.numSubjects.Value = num2str(conf.numSubjects);
            app.BrainRange.Value = num2str(conf.BrainRange);
            app.UserFrameRate.Value = num2str(conf.UserFrameRate);
            app.videoOutput.Value = conf.videoOutput;
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ConfigDialog_OpeningFcn(app)
        %executes the updateUI to change everything accordingly to conf
            updateUI(app);
        end

        % Button pushed function: Cancel
        function Cancel_Callback(app, event)
        %close window
            delete(app);
        end

        % Button pushed function: allOutputs
        function allOutputs_Callback(app, event)
            %turn on all output settings
            app.rawEEG.Value = 1;
            app.rawEDA.Value = 1;
            app.qualityFigures.Value = 1;
            app.detrendedEDA.Value = 1;
            app.frequency.Value = 1;
            app.statistics.Value = 1;
            app.recurrenceFig.Value = 1;
            app.behavioralCharacteristics.Value = 1;
            app.rawHRV.Value = 1;
            app.subStimuIntEDA.Value = 1;
            app.topoplot.Value = 1;
            app.brainactivity.Value = 1;
        end

        % Button pushed function: noOutputs
        function noOutputs_Callback(app, event)
            %turn off all output settings
            app.rawEEG.Value = 0;
            app.rawEDA.Value = 0;
            app.qualityFigures.Value = 0;
            app.detrendedEDA.Value = 0;
            app.frequency.Value = 0;
            app.statistics.Value = 0;
            app.recurrenceFig.Value = 0;
            app.behavioralCharacteristics.Value = 0;
            app.rawHRV.Value = 0;
            app.subStimuIntEDA.Value = 0;
            app.topoplot.Value = 0;
            app.brainactivity.Value = 0;
        end

        % Button pushed function: ok
        function ok_Callback(app, event)
            global conf
            % initialize Output flags - update conf
            conf.EEG_DEVICE_USED = app.rawEEG.Value;
            conf.FrequencyFig = app.frequency.Value;
            conf.EDA_DEVICE_USED = app.rawEDA.Value;
            conf.DetrendedEDAFig = app.detrendedEDA.Value;
            conf.RecurrenceFig = app.recurrenceFig.Value;
            conf.HRV_DEVICE_USED = app.rawHRV.Value;
            conf.SubStimuIntEDAFig = app.subStimuIntEDA.Value;
            conf.QualityFig = app.qualityFigures.Value;
            conf.BehaveFig = app.behavioralCharacteristics.Value;
            conf.Statistics = app.statistics.Value;
            conf.EEGCutoffValue = app.EEGCutoffValue.Value;
            conf.numSubjects = app.numSubjects.Value;
            conf.topoplot = app.topoplot.Value;
            conf.TopoRange = app.TopoRange.Value;
            conf.brainactivity = app.brainactivity.Value;
            conf.BrainRange = app.BrainRange.Value;
            conf.UserFrameRate = app.UserFrameRate.Value;
            conf.videoOutput = app.videoOutput.Value;


            % Quality Settings
            valid = 1;
            str = app.lowerEEGThreshold.Value;
            %check string, if inputs are valid
            if isempty(str)
                app.lowerEEGThreshold = -100;
                warndlg('Input must be numerical','Invalid lower threshold');
                valid = 0;
            else
                %save string in conf
                conf.LowerThreshold = str2double(str);
            end
            
            str = app.upperEEGThreshold.Value;
            if isempty(str)
                app.upperEEGThreshold = 100;
                warndlg('Input must be numerical','Invalid upper threshold');
                valid = 0;
            else
                conf.UpperThreshold = str2double(str);
            end
            
            str = app.recurrenceTreshold.Value;
            if isempty(str)
                app.recurrenceTreshold = 0;
                warndlg('Input must be numerical','Invalid recurrence threshold');
                valid = 0;
            else
                conf.RecurrenceThreshold = str2double(str);
            end
            
            str = app.qualityIndex.Value;
            if isempty(str)
                app.qualityIndex = 10;
                warndlg('Input must be numerical','Invalid quality index');
                valid = 0;
            else
                conf.QualityIndex = str2double(str);
            end
            
            str = app.EEGCutoffValue.Value;
            if isempty(str)
                app.EEGCutoffValue = 10;
                warndlg('Input must be numerical','Invalid EEG CutOff Value');
                valid = 0;
            else
                conf.EEGCutoffValue = str2double(str);
            end
            
            str = app.TopoRange.Value;
            if isempty(str)
                app.TopoRange = 1000;
                warndlg('Input must be numerical','Invalid Interval');
                valid = 0;
            elseif  str2double(str) < 1000
                warndlg(['Values for Topology maps wrong!' ,newline, 'Input value needs to be 1000ms or higher'],'Invalid Interval');
                valid = 0;
            else
                conf.TopoRange = str2double(str);
            end
            
            str = app.BrainRange.Value;
            if isempty(str)
                app.BrainRange = 2000;
                warndlg('Input must be numerical','Invalid Interval');
                valid = 0;
            else
                conf.BrainRange = str2double(str);
            end
            
            str = app.UserFrameRate.Value;
            if isempty(str)
                app.UserFrameRate = 30;
                warndlg('Input must be numerical','Invalid Interval');
                valid = 0;
            else
                conf.UserFrameRate = str2double(str);
            end
            
            
            str = app.numSubjects.Value;
            if isempty(str)
                app.numSubjects = 1;
                warndlg('Input must be numerical','Invalid Interval');
                valid = 0;
            else
                conf.numSubjects = str2double(str);
            end
            
            if app.topoplot.Value == 0 && conf.signalSpec == 1 
                warndlg(['Warning: To export the power signal spetra data you needed to activate "2D Topology Map" in settings!',newline,'Please unselect the export of the signal spectra'])
                valid = 0;
            end
            
            %if all inputs are valid, close window
            if (valid==1)
                delete(app);
            end
        end

        % Button pushed function: ElectrodeDialogButton
        function ElectrodeDialogButton_Callback(app, event)
            %Calls electrode dialog
            ElectrodesDialog_App;
        end

        % Callback function
        function exportButtonPushed(app, event)
            ExportDialog_App;
        end

        % Button pushed function: ExportSettingsButton
        function ExportSettingsButtonPushed(app, event)
            ExportDialog_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Settings Dialog';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Output Settings';
            app.behavioralCharacteristics = uicheckbox(app.uipanel1);
            app.behavioralCharacteristics.Text = 'Behavioral Characteristics';
            app.rawEEG = uicheckbox(app.uipanel1);
            app.rawEEG.Text = 'Raw EEG';
            app.frequency = uicheckbox(app.uipanel1);
            app.frequency.Text = 'EEG Frequencies';
            app.rawEDA = uicheckbox(app.uipanel1);
            app.rawEDA.Text = 'Raw EDA';
            app.rawHRV = uicheckbox(app.uipanel1);
            app.rawHRV.Text = 'Raw HRV';
            app.detrendedEDA = uicheckbox(app.uipanel1);
            app.detrendedEDA.Text = 'Detrended EDA';
            app.subStimuIntEDA = uicheckbox(app.uipanel1);
            app.subStimuIntEDA.Text = 'Subvideo EDA';
            app.qualityFigures = uicheckbox(app.uipanel1);
            app.qualityFigures.Text = 'Quality Figures';
            app.statistics = uicheckbox(app.uipanel1);
            app.statistics.Text = 'Statistics';
            app.recurrenceFig = uicheckbox(app.uipanel1);
            app.recurrenceFig.Text = 'HRV Recurrence';
            app.topoplot = uicheckbox(app.uipanel1);
            app.topoplot.Text = '2D Topology Map';
            app.brainactivity = uicheckbox(app.uipanel1);
            app.brainactivity.Text = 'Brain activity Plot';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Reccurence Plot Settings';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Text = 'Threshold';
            app.recurrenceTreshold = uieditfield(app.uipanel3, 'text');
            app.recurrenceTreshold.Value = '0';
            app.ok = uibutton(app.figure1, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @ok_Callback, true);
            app.ok.Text = 'Ok';
            app.Cancel = uibutton(app.figure1, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @Cancel_Callback, true);
            app.Cancel.Text = 'Cancel';
            app.allOutputs = uibutton(app.figure1, 'push');
            app.allOutputs.ButtonPushedFcn = createCallbackFcn(app, @allOutputs_Callback, true);
            app.allOutputs.Text = 'All';
            app.noOutputs = uibutton(app.figure1, 'push');
            app.noOutputs.ButtonPushedFcn = createCallbackFcn(app, @noOutputs_Callback, true);
            app.noOutputs.Text = 'None';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Select electrodes';
            app.ElectrodeDialogButton = uibutton(app.uipanel5, 'push');
            app.ElectrodeDialogButton.ButtonPushedFcn = createCallbackFcn(app, @ElectrodeDialogButton_Callback, true);
            app.ElectrodeDialogButton.Text = 'Select Electrodes';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'EEG Quality Settings';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Text = 'Quality Index';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Text = 'Upper Threshold';
            app.text2 = uilabel(app.uipanel2);
            app.text2.Text = 'Lower Threshold';
            app.lowerEEGThreshold = uieditfield(app.uipanel2, 'text');
            app.lowerEEGThreshold.Value = '-100';
            app.text1 = uilabel(app.uipanel2);
            app.text1.Text = 'µV';
            app.upperEEGThreshold = uieditfield(app.uipanel2, 'text');
            app.upperEEGThreshold.Value = '100';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Text = 'µV';
            app.qualityIndex = uieditfield(app.uipanel2, 'text');
            app.qualityIndex.Value = '10';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Text = '%';
            app.EEGCutoffValue = uieditfield(app.uipanel2, 'text');
            app.EEGCutoffValue.Value = '10';
            app.EEGCutoffValueInSec = uilabel(app.uipanel2);
            app.EEGCutoffValueInSec.Text = 'EEG Cut-Off Value';
            app.sLabel = uilabel(app.uipanel2);
            app.sLabel.Text = 's';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Video Output Settings';
            app.text21_3 = uilabel(app.uipanel6);
            app.text21_3.Text = 'Set frames per second for video output';
            app.UserFrameRate = uieditfield(app.uipanel6, 'text');
            app.UserFrameRate.Value = '30';
            app.videoOutput = uicheckbox(app.uipanel6);
            app.videoOutput.Text = 'Video output';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Range for prints';
            app.text21 = uilabel(app.uipanel7);
            app.text21.Text = '2D Topology maps [ms]';
            app.TopoRange = uieditfield(app.uipanel7, 'text');
            app.TopoRange.Value = '500';
            app.text21_2 = uilabel(app.uipanel7);
            app.text21_2.Text = 'Brain activity Plot [ms]';
            app.BrainRange = uieditfield(app.uipanel7, 'text');
            app.BrainRange.Value = '2000';
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Title = 'Number of subjects';
            app.numSubjects = uieditfield(app.uipanel8, 'text');
            app.numSubjects.Value = '1';
            app.text24 = uilabel(app.uipanel8);
            app.text24.Text = 'Subjects';
            app.SettingsforanalyseLabel = uilabel(app.figure1);
            app.SettingsforanalyseLabel.Text = 'Settings for analyse';
            app.Label = uilabel(app.figure1);
            app.Label.Text = '';
            app.ExportSettingsButton = uibutton(app.figure1, 'push');
            app.ExportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSettingsButtonPushed, true);
            app.ExportSettingsButton.Text = 'Export Settings';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigDialog_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @ConfigDialog_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end