classdef ExportDialog_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        uipanel1                       matlab.ui.container.Panel
        EEGData                        matlab.ui.control.CheckBox
        EDAData                        matlab.ui.control.CheckBox
        HRVData                        matlab.ui.control.CheckBox
        signalSpec                     matlab.ui.control.CheckBox
        ok                             matlab.ui.control.Button
        Cancel                         matlab.ui.control.Button
        SettingsforexportingdataLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        allOutputs                     matlab.ui.control.Button
        noOutputs                      matlab.ui.control.Button
    end

    %This is the Settings GUI for the AdAnalyser.

    % The user can set the different settings for the analysis.
    %
    % Author: Tim Kreitzberg
    %

    %function to update GUI accordingly to their values in conf
    methods (Access = private)
        function updateUI(app)
            global conf;
            app.EEGData.Value = conf.EEGData;
            app.EDAData.Value = conf.EDAData;
            app.HRVData.Value = conf.HRVData;
            app.signalSpec.Value = conf.signalSpec;

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ConfigDialog_OpeningFcn(app)
        %executes the updateUI to change everything accordingly to conf
            updateUI(app);
        end

        % Button pushed function: Cancel
        function Cancel_Callback(app, event)
        %close window
            delete(app);
        end

        % Button pushed function: allOutputs
        function allOutputs_Callback(app, event)
            %turn on all output settings
            app.EEGData.Value = 1;
            app.EDAData.Value = 1;
            app.HRVData.Value = 1;
            app.signalSpec.Value = 1;

        end

        % Button pushed function: noOutputs
        function noOutputs_Callback(app, event)
            %turn off all output settings
            app.EEGData.Value = 0;
            app.EDAData.Value = 0;
            app.HRVData.Value = 0;
            app.signalSpec.Value = 0;

        end

        % Button pushed function: ok
        function ok_Callback(app, event)
            global conf
            % initialize Output flags - update conf
            conf.EEGData = app.EEGData.Value;   
            conf.EDAData = app.EDAData.Value; 
            conf.HRVData = app.HRVData.Value;
            conf.signalSpec = app.signalSpec.Value;
            
            if conf.topoplot == 0 && app.signalSpec.Value == 1 
                warndlg(['Warning: To export the power signal spetra data you needed to activate "2D Topology Map" in settings!',newline,'Please unselect the export of the signal spectra'])
            else
                delete(app)
            end
        end

        % Callback function
        function ElectrodeDialogButton_Callback(app, event)
            %Calls electrode dialog
            ElectrodesDialog_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Settings Dialog';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Export settings';
            app.EEGData = uicheckbox(app.uipanel1);
            app.EEGData.Text = 'EEG Data';
            app.EDAData = uicheckbox(app.uipanel1);
            app.EDAData.Text = 'EDA Data';
            app.HRVData = uicheckbox(app.uipanel1);
            app.HRVData.Text = 'HRV Data';
            app.signalSpec = uicheckbox(app.uipanel1);
            app.signalSpec.Text = 'Signal Spectra Data';
            app.ok = uibutton(app.figure1, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @ok_Callback, true);
            app.ok.Text = 'Ok';
            app.Cancel = uibutton(app.figure1, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @Cancel_Callback, true);
            app.Cancel.Text = 'Cancel';
            app.SettingsforexportingdataLabel = uilabel(app.figure1);
            app.SettingsforexportingdataLabel.Text = 'Settings for exporting data';
            app.Label = uilabel(app.figure1);
            app.Label.Text = '';
            app.allOutputs = uibutton(app.figure1, 'push');
            app.allOutputs.ButtonPushedFcn = createCallbackFcn(app, @allOutputs_Callback, true);
            app.allOutputs.Text = 'All';
            app.noOutputs = uibutton(app.figure1, 'push');
            app.noOutputs.ButtonPushedFcn = createCallbackFcn(app, @noOutputs_Callback, true);
            app.noOutputs.Text = 'None';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportDialog_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @ConfigDialog_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end